classdef Mech_Inline_Slider_Crank_Mechanism < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ForceFrame                     matlab.ui.container.Panel
        Button_Force                   matlab.ui.control.Button
        PonLinkDropDownLabel           matlab.ui.control.Label
        PonLinkDropDown                matlab.ui.control.DropDown
        PNEditFieldLabel               matlab.ui.control.Label
        PNEditField                    matlab.ui.control.NumericEditField
        EvaluateButton_Force           matlab.ui.control.Button
        ResetButton_6                  matlab.ui.control.Button
        clearButton                    matlab.ui.control.Button
        AngleofActionEditFieldLabel    matlab.ui.control.Label
        AngleofActionEditField         matlab.ui.control.NumericEditField
        VelocityEditFieldLabel         matlab.ui.control.Label
        VelocityEditFieldForce         matlab.ui.control.NumericEditField
        UIAxes5                        matlab.ui.control.UIAxes
        PositionFrame                  matlab.ui.container.Panel
        Button_Position                matlab.ui.control.Button
        LinkDropDownLabel              matlab.ui.control.Label
        LinkDropDown                   matlab.ui.control.DropDown
        EvaluateButton_pos             matlab.ui.control.Button
        ClearButton_4                  matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        AccelerationFrame              matlab.ui.container.Panel
        Button_Acc                     matlab.ui.control.Button
        EvaluateButton                 matlab.ui.control.Button
        LinkDropDown_2Label            matlab.ui.control.Label
        LinkDropDown_2                 matlab.ui.control.DropDown
        InputvelocityEditFieldLabel    matlab.ui.control.Label
        InputVelocity_accEditField     matlab.ui.control.NumericEditField
        ClearButton_3                  matlab.ui.control.Button
        UIAxes3                        matlab.ui.control.UIAxes
        VelocityFrame                  matlab.ui.container.Panel
        Button_vel                     matlab.ui.control.Button
        EvaluateButton_vel             matlab.ui.control.Button
        TypeDropDownLabel              matlab.ui.control.Label
        TypeDropDown                   matlab.ui.control.DropDown
        InputVelocityEditFieldLabel    matlab.ui.control.Label
        InputVelocityEditField         matlab.ui.control.NumericEditField
        ClearButton_2                  matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        AnimationFrame                 matlab.ui.container.Panel
        Button_Animate                 matlab.ui.control.Button
        SliderLabel                    matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        Switch_2                       matlab.ui.control.Switch
        TextArea_ani                   matlab.ui.control.TextArea
        DisplayButton                  matlab.ui.control.Button
        UIAxes4                        matlab.ui.control.UIAxes
        MainFrame                      matlab.ui.container.Panel
        CrankLengthmEditFieldLabel     matlab.ui.control.Label
        CrankLengthmEditField          matlab.ui.control.EditField
        ConnectingrodLengthmEditFieldLabel  matlab.ui.control.Label
        ConnectingrodLengthmEditField  matlab.ui.control.EditField
        ValidateButton                 matlab.ui.control.Button
        InputRangeEditFieldLabel       matlab.ui.control.Label
        InputRangeEditField            matlab.ui.control.EditField
        EditField                      matlab.ui.control.EditField
        ValidationTextAreaLabel        matlab.ui.control.Label
        ValidationTextArea             matlab.ui.control.TextArea
        AnalyseButton                  matlab.ui.control.Button
        InputRangeDropDownLabel        matlab.ui.control.Label
        InputRangeDropDown             matlab.ui.control.DropDown
        AnimateButton                  matlab.ui.control.Button
        KinematicsDropDownLabel        matlab.ui.control.Label
        KinematicsDropDown             matlab.ui.control.DropDown
        ResetButton                    matlab.ui.control.Button
        SaveOutputButton               matlab.ui.control.Button
        Switch_3                       matlab.ui.control.Switch
    end

    
    properties (Access = public)
        Property % Description
        theta=0;
        Torque=0;
       
        r1 =0;
        r2 =0;
        r3 =0;
        theta2 =0;
        theta3 =0;
        w_crank =0;
        w_conn =0;
        alpha_crank =0;
        alpha_conn= 0;
        v_s =0;
        acc_s =0;
        P =0;
        For=0;
        buttonpush
        Time=0;
    end


    methods (Access = public)
        
        function [r1_,r2_,r3_,theta2_,theta3_] = Position(app)
             r2 = str2num(app.CrankLengthmEditField.Value);
          r3 = str2num(app.ConnectingrodLengthmEditField.Value);
          
            if(app.InputRangeDropDown.Value=="Position of slider , r ⃗_1")
                r1 = str2num(app.InputRangeEditField.Value);
               
                for i =1: length(r1)
                   
                theta2(i) =acosd(((r1(i)^2)+(r2^2)-(r3^2))/(2*r1(i)*r2));
                theta3(i) = asin((-r2*sind(theta2(i)))/r3);
                end
           
               
            elseif(app.InputRangeDropDown.Value=="Angle of Crank    , θ_2")
               theta2=str2num(app.InputRangeEditField.Value); 
                theta3 = asin((-r2*sind(theta2))/r3);
                 r1=r2.*cosd(theta2)+r3.*cosd(theta3);
            end
            
           r1_=r1;
           r2_=r2;
           r3_=r3;
           theta2_=theta2;
           theta3_=theta3;
           
        end
        
        function [w_crank1,w_conn1,v_s1,v_crank1,v_conn1,T_] = Velocity(app)
            
             [r1,r2,r3,theta2,theta3] = Position(app);
            
                n=(r3/r2);
                
              
            if(app.InputRangeDropDown.Value=="Position of slider , r ⃗_1")
             
                v_s=app.InputVelocityEditField.Value;
                v_s1 = v_s;
                 T = (r1)./v_s;
                
                for i = 1:length(theta2)
                   
                     w_crank(i) = (((r1(i)^2)-r2^2+r3^3)*v_s)/(2*r1(i)*sind(theta2(i))*(r2^2));
                      w_conn(i) =(-r2*cosd(theta2(i))*w_crank(i))/(r3*cosd(theta3(i)));
                       
                end
                     v_crankx = r2*cosd(theta2).*w_crank;
                     v_cranky = r2*sind(theta2).*w_crank;
                       v_connx = r3*cosd(theta3).*w_conn;
                       v_conny = r3*sind(theta3).*w_conn;
                        v_crank1=[v_crankx; v_cranky];
              
             elseif(app.InputRangeDropDown.Value=="Angle of Crank    , θ_2")
                
                 w_crank = app.InputVelocityEditField.Value;
                 w_crank1=w_crank;
                 T = (theta2)./(w_crank);
               for i = 1:length(theta2)
                   
                 w_conn(i) =(-r2*cosd(theta2(i))*w_crank)/(r3*cosd(theta3(i)));
                 v_s(i)=((-r2*sind(theta2(i))*w_crank)+(-r3*sind(theta3(i))*w_conn(i)));
                     
               end
                     v_connx = r3*cosd(theta3).*w_conn;
                     v_conny = r3*sind(theta3).*w_conn;
                      v_crank1=[0;0];
                 
            end
            
                   w_crank1=w_crank;
                    w_conn1=w_conn;
                    v_s1=v_s;
                   T_=T;
                    v_conn1 =[v_connx; v_conny];
                    app.Time=T;
        end
        
        function [alpha_crank1,alpha_conn1,acc_s1] = Acceleration(app)
             [r1,r2,r3,theta2,theta3] = Position(app);
             
             if(app.InputRangeDropDown.Value=="Position of slider , r ⃗_1")
                 
                  v_s=app.InputVelocity_accEditField.Value;
                  app.InputVelocityEditField.Value = v_s;
                  [w_crank,w_conn,v_s,v_crank,v_conn,T]=Velocity(app);
                
                 acc_s=0;
                 for i = 1:length(theta2)
                 A(i)=(((r1(i)^2)+(r2^2)-(r3^2))*(v_s^2))/(2*(r1(i)^2)*(r2^2)*sind(theta2(i)));
                 B(i)=((w_crank(i)^2)*cosd(theta2(i)))/(sind(theta2(i)));
                 alpha_crank(i)=A(i)-B(i);
                 
                 C(i)=((-r2/r3)*((-sind(theta2(i))*(w_crank(i)^2))+(alpha_crank(i)*cosd(theta2(i)))))+((w_conn(i)^2)*sind(theta3(i)));
                 alpha_conn(i)=C(i)/(cosd(theta3(i)));
                 end
             elseif(app.InputRangeDropDown.Value=="Angle of Crank    , θ_2")
                  w_crank = app.InputVelocity_accEditField.Value;
                   app.InputVelocityEditField.Value = w_crank;
                  [w_crank,w_conn,v_s,v_crank,v_conn,T]=Velocity(app);
                 
                 alpha_crank=0;
                 
                 for i = 1:length(theta2)
                          C(i)=((-r2/r3)*(-sind(theta2(i))*(w_crank^2)))+((w_conn(i)^2)*sind(theta3(i)));
                          alpha_conn(i)=C(i)/(cosd(theta3(i)));
                          acc_s(i)=(-r2*cosd(theta2(i))*(w_crank^2))-(r3*((cosd(theta3(i))*(w_conn(i)^2))+(sind(theta3(i))*alpha_conn(i))));
                          
                 end
             end
            
            alpha_crank1 = alpha_crank;
            alpha_conn1 = alpha_conn;
            acc_s1 = acc_s;
            
        end
        
        function [P_,theta_,Torque_,For_] = Force(app)
              [r1,r2,r3,theta2,theta3]=Position(app);
           if(app.InputRangeDropDown.Value=="Position of slider , r ⃗_1")
               app.VelocityEditFieldLabel.Text="Velocity of Slider";
              
           elseif(app.InputRangeDropDown.Value=="Angle of Crank    , θ_2")
                app.VelocityEditFieldLabel.Text="Ang. Velocity Of Crank";
             
           end
            app.InputVelocity_accEditField.Value = app.VelocityEditFieldForce.Value;
           [w_crank,w_conn,v_s,v_crank,v_conn,T_]=Velocity(app);
           [alpha_crank,alpha_conn,acc_s]=Acceleration(app);
           T = T_;
             P=app.PNEditField.Value;
           theta =app.AngleofActionEditField.Value;
           
           if(app.PonLinkDropDown.Value=="Slider")
                Torque = -(P*cosd(theta)*v_s)./(w_crank);
                For=1;
                
           elseif(app.PonLinkDropDown.Value=="Connecting Rod")
               ang = 180-(theta)-theta3;
               F_32=(P*cosd(ang))/(2*cosd(theta3));
               r2_vec=r2*(cosd(theta2)+sind(theta2));
                Torque = r2_vec*F_32;
                For=2;
           end
           P_=P;
           theta_=theta;
           Torque_=Torque;
           For_=For;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          
               if(app.InputRangeDropDown.Value=="Position of slider , r ⃗_1")
               app.InputRangeEditFieldLabel.Text="Displacement of Slider (m)";
              
              elseif(app.InputRangeDropDown.Value=="Angle of Crank    , θ_2")
                  app.InputRangeEditFieldLabel.Text="Angle of crank (deg)";
             
               end
               
               app.Switch_3.Value = "Off";
               app.MainFrame.Enable = "off";
          
        end

        % Button pushed function: AnimateButton
        function AnimateButtonPushed(app, event)
          
                app.ValidationTextArea.Value = 'Animation of Slide-Crank Mechanism';
                app.PositionFrame.Visible='off';
                app.VelocityFrame.Visible='off';
                app.MainFrame.Visible='off';
                app.AccelerationFrame.Visible='off';
                app.AnimationFrame.Visible='on';
                app.ForceFrame.Visible = 'off';
                
                
        end

        % Button pushed function: AnalyseButton
        function AnalyseButtonPushed(app, event)
           
           
            if(app.KinematicsDropDown.Value=="Position")
                app.ValidationTextArea.Value = 'Position Analysis';
                app.PositionFrame.Visible='on';
                app.VelocityFrame.Visible='off';
                app.MainFrame.Visible='off';
                app.AccelerationFrame.Visible='off';
                app.AnimationFrame.Visible='off';
                app.ForceFrame.Visible = 'off';
            elseif(app.KinematicsDropDown.Value=="Velocity")
                
                if(app.InputRangeDropDown.Value=="Position of slider , r ⃗_1")
                    
                    app.InputVelocityEditFieldLabel.Text="Velocity of Slider";
                    app.TypeDropDown.Items = ["Angular Velocity of Crank" "Angular Velocity of Connectiong Rod"];
                    app.TypeDropDown.ItemsData = ["Angular Velocity of Crank" "Angular Velocity of Connectiong Rod"];
                    app.TypeDropDown.Value="Angular Velocity of Crank";
                elseif(app.InputRangeDropDown.Value=="Angle of Crank    , θ_2")
                    app.InputVelocityEditFieldLabel.Text="Angular velocity of Crank";
                    app.TypeDropDown.Items = ["Angular Velocity of Connectiong Rod" "Velocity of Slider"];
                    app.TypeDropDown.ItemsData = ["Angular Velocity of Connectiong Rod" "Velocity of Slider"];
                    app.TypeDropDown.Value="Angular Velocity of Connectiong Rod";
                end
                
                
                app.ValidationTextArea.Value = 'Velocity Analysis';
                app.PositionFrame.Visible='off';
                app.VelocityFrame.Visible='on';
                app.MainFrame.Visible='off';
                app.AccelerationFrame.Visible='off';
                app.AnimationFrame.Visible='off';
                app.ForceFrame.Visible = 'off';
            elseif(app.KinematicsDropDown.Value=="Acceleration")
                      if(app.InputRangeDropDown.Value=="Position of slider , r ⃗_1")
                          
                         app.InputvelocityEditFieldLabel.Text ="Velocity of Slider";
                         app.LinkDropDown_2.Items=["Ang. Acc of Crank" "Ang. Acc of Connecting Rod"];
                         app.LinkDropDown_2.ItemsData=["Ang. Acc of Crank" "Ang. Acc of Connecting Rod"];
                         app.LinkDropDown_2.Value="Ang. Acc of Crank";
                          
                      elseif(app.InputRangeDropDown.Value=="Angle of Crank    , θ_2")
                          app.InputvelocityEditFieldLabel.Text="Angular velocity of Crank";
                         app.LinkDropDown_2.Items=["Ang. Acc of Connecting Rod" "Acceleration of Slider"];
                         app.LinkDropDown_2.ItemsData=["Ang. Acc of Connecting Rod" "Acceleration of Slider"];
                         app.LinkDropDown_2.Value="Ang. Acc of Connecting Rod";
                      end


                app.ValidationTextArea.Value = 'Acceleration Analysis';
                app.PositionFrame.Visible='off';
                app.VelocityFrame.Visible='off';
                app.MainFrame.Visible='off';
                app.AccelerationFrame.Visible='on';
                app.AnimationFrame.Visible='off';
                app.ForceFrame.Visible = 'off';
            elseif(app.KinematicsDropDown.Value=="Static Force")
                app.ValidationTextArea.Value = 'Static Force Analysis';
                app.PositionFrame.Visible='off';
                app.VelocityFrame.Visible='off';
                app.MainFrame.Visible='off';
                app.AccelerationFrame.Visible='off';
                app.AnimationFrame.Visible='off';
                app.ForceFrame.Visible = 'on';
                
            else
                 app.ValidationTextArea.Value = 'Error in Panels';
                
            end
               
          
        end

        % Button pushed function: Button_Acc
        function Button_AccPushed(app, event)
               
                app.PositionFrame.Visible='off';
                app.VelocityFrame.Visible='off';
                app.MainFrame.Visible='on';
                app.AccelerationFrame.Visible='off';
                app.AnimationFrame.Visible='off';
                app.ForceFrame.Visible = 'off';
        end

        % Button pushed function: Button_vel
        function Button_velPushed(app, event)
               
                app.PositionFrame.Visible='off';
                app.VelocityFrame.Visible='off';
                app.MainFrame.Visible='on';
                app.AccelerationFrame.Visible='off';
                app.AnimationFrame.Visible='off';
                app.ForceFrame.Visible = 'off';
        end

        % Button pushed function: Button_Animate
        function Button_AnimatePushed(app, event)
              
                app.PositionFrame.Visible='off';
                app.VelocityFrame.Visible='off';
                app.MainFrame.Visible='on';
                app.AccelerationFrame.Visible='off';
                app.AnimationFrame.Visible='off';
                app.ForceFrame.Visible = 'off';
        end

        % Button pushed function: Button_Force
        function Button_ForcePushed(app, event)
                
                app.PositionFrame.Visible='off';
                app.VelocityFrame.Visible='off';
                app.MainFrame.Visible='on';
                app.AccelerationFrame.Visible='off';
                app.AnimationFrame.Visible='off';
                app.ForceFrame.Visible = 'off';
        end

        % Button pushed function: Button_Position
        function Button_PositionPushed(app, event)
             
                app.PositionFrame.Visible='off';
                app.VelocityFrame.Visible='off';
                app.MainFrame.Visible='on';
                app.AccelerationFrame.Visible='off';
                app.AnimationFrame.Visible='off';
                app.ForceFrame.Visible = 'off';
        end

        % Button pushed function: ValidateButton
        function ValidateButtonPushed(app, event)
            
             r2 = str2num(app.CrankLengthmEditField.Value);
          r3 = str2num(app.ConnectingrodLengthmEditField.Value);
            
            if(r3>r2)
                if(app.InputRangeDropDown.Value=="Position of slider , r ⃗_1")
                  r1 = str2num(app.InputRangeEditField.Value);
                  L=r2+r3;
                  if((r1(1)>=(r3-r2)) && (r1(length(r1))<=(r3+r2)))
                      app.ValidationTextArea.Value = "Mechanism valid";
                       app.AnalyseButton.Enable="on";
                      app.AnimateButton.Enable="on";
                  else
                      app.ValidationTextArea.Value = "Mechanism Invalid";
                      app.AnalyseButton.Enable="off";
                      app.AnimateButton.Enable="off";
                  end
                  
                  
                elseif(app.InputRangeDropDown.Value=="Angle of Crank    , θ_2")
                 theta2=str2num(app.InputRangeEditField.Value); 
                 
                 if((theta2(1)>=0)   && (theta2(length(theta2))<=360))
                       app.ValidationTextArea.Value = "Mechanism valid";
                         app.AnalyseButton.Enable="on";
                      app.AnimateButton.Enable="on";
                 else
                      app.ValidationTextArea.Value = "Mechanism Invalid";
                      app.AnalyseButton.Enable="off";
                      app.AnimateButton.Enable="off";
                 end
                 
                end
                
            else
                app.ValidationTextArea.Value="Invalid mechanism"
                 app.AnalyseButton.Enable="off";
                      app.AnimateButton.Enable="off";
                
            end
            
        end

        % Callback function: EvaluateButton_pos, VelocityFrame
        function EvaluateButton_posPushed(app, event)
            
          % r2 = crank ; r3 = connecting rod
          r2 = str2num(app.CrankLengthmEditField.Value);
          r3 = str2num(app.ConnectingrodLengthmEditField.Value);
          theta2=0;
          theta3=0;


            if(app.InputRangeDropDown.Value=="Position of slider , r ⃗_1")
                r1 = str2num(app.InputRangeEditField.Value);
               
                for i =1: length(r1)
                   
                theta2(i) =acosd(((r1(i)^2)+(r2^2)-(r3^2))/(2*r1(i)*r2));
                theta3(i) = asin((-r2*sind(theta2(i)))/r3);
               
                end
               
               if(app.LinkDropDown.Value=="Crank")
                   x = r2*cosd(theta2);
                   y = r2*sind(theta2);
                 
                    plot(x,y,"Parent",app.UIAxes);
                 
                 
                        app.UIAxes.XLabel.String="x coordinate of Crank ";
                         app.UIAxes.YLabel.String="y coordinate of Crank";
                         app.UIAxes.Title.String="Position Aanlysis"; 
               elseif(app.LinkDropDown.Value=="Slider")
                   plot(theta2,r1,"Parent",app.UIAxes);
                         app.UIAxes.XLabel.String="\theta2";
                         app.UIAxes.YLabel.String="Displacement of Slider";
                         app.UIAxes.Title.String="Position Aanlysis"; 
                   
               end
               
            elseif(app.InputRangeDropDown.Value=="Angle of Crank    , θ_2")
               theta2=str2num(app.InputRangeEditField.Value); 
                theta3 = asin((-r2*sind(theta2))/r3);
                
                 if(app.LinkDropDown.Value=="Crank")
                   x = r2*cosd(theta2);
                   y=  r2*sind(theta2);
                   
                  plot(x,y,"Parent",app.UIAxes);
                         app.UIAxes.XLabel.String="x coordinate of Crank ";
                         app.UIAxes.YLabel.String="y coordinate of Crank";
                         app.UIAxes.Title.String="Position Aanlysis"; 
                 elseif(app.LinkDropDown.Value=="Slider")
                     
                   r1=r2.*cosd(theta2)+r3.*cosd(theta3);
                   
                    plot(theta2,r1,"Parent",app.UIAxes);
                        app.UIAxes.XLabel.String="\theta2";
                         app.UIAxes.YLabel.String="Displacement of Slider";
                         app.UIAxes.Title.String="Position Aanlysis"; 
                     
                 end
         
            end
             app.r1=r1;
              app.r2=r2;
              app.r3=r3;
              app.theta2=theta2;
              app.theta3=theta3;


        end

        % Button pushed function: EvaluateButton_vel
        function EvaluateButton_velPushed(app, event)
            
           [r1,r2,r3,theta2,theta3] = Position(app);
           [w_crank,w_conn,v_s,v_crank,v_conn,T_]=Velocity(app);
            
            T=  T_;
            if(app.InputRangeDropDown.Value=="Position of slider , r ⃗_1") 
                
                if(app.TypeDropDown.Value=="Angular Velocity of Crank")
                    
                    
                     plot(T,w_crank,"Parent",app.UIAxes2);
                     app.UIAxes2.XLabel.String="Time (sec)";
                         app.UIAxes2.YLabel.String="Angular Velocity of Crank";
                         app.UIAxes2.Title.String="Velocity Aanlysis"; 
                     
                elseif(app.TypeDropDown.Value=="Angular Velocity of Connectiong Rod")
                   
                     plot(T,w_conn,"Parent",app.UIAxes2);
                     app.UIAxes2.XLabel.String="Time (sec)";
                         app.UIAxes2.YLabel.String="Angular Velocity of Connecting Rod";
                         app.UIAxes2.Title.String="Velocity Aanlysis"; 
                end
                
             elseif(app.InputRangeDropDown.Value=="Angle of Crank    , θ_2")
             
              
                if(app.TypeDropDown.Value=="Angular Velocity of Connectiong Rod")
                     
                     
                    plot(T,w_conn,"Parent",app.UIAxes2);
                         app.UIAxes2.XLabel.String="Time (sec)";
                         app.UIAxes2.YLabel.String="Angular Velocity of Connecting Rod";
                         app.UIAxes2.Title.String="Velocity Aanlysis"; 
                  
                elseif(app.TypeDropDown.Value=="Velocity of Slider")
                     plot(T,v_s,"Parent",app.UIAxes2);
                     app.UIAxes2.XLabel.String="Time (sec)";
                         app.UIAxes2.YLabel.String="Velocity of Slider";
                         app.UIAxes2.Title.String="Velocity Aanlysis"; 
                end
                 
            end
             app.r1=r1;
              app.r2=r2;
              app.r3=r3;
              app.theta2=theta2;
              app.theta3=theta3;
              app.w_crank=w_crank;
              app.w_conn=w_conn;
              app.v_s=v_s;
            
           
        end

        % Button pushed function: EvaluateButton
        function EvaluateButton_accPushed(app, event)
           [r1,r2,r3,theta2,theta3]=Position(app);
           [w_crank,w_conn,v_s,v_crank,v_conn,T_]=Velocity(app);
           [alpha_crank,alpha_conn,acc_s]=Acceleration(app);
           T=T_;
              if(app.InputRangeDropDown.Value=="Position of slider , r ⃗_1")
                if(app.LinkDropDown_2.Value=="Ang. Acc of Crank")
                    
                     plot(T,alpha_crank,"Parent",app.UIAxes3);
           app.UIAxes3.XLabel.String="Time (sec)";
           app.UIAxes3.YLabel.String="Ang. Acc. Of Crank";
           app.UIAxes3.Title.String="Acceleration Aanlysis";
                      
                elseif(app.LinkDropDown_2.Value=="Ang. Acc of Connecting Rod")
                      plot(T,alpha_conn,"Parent",app.UIAxes3);
                      app.UIAxes3.XLabel.String="Time (sec)";
           app.UIAxes3.YLabel.String="Ang. Acc. Of Connecting Rod";
           app.UIAxes3.Title.String="Acceleration Aanlysis";
                end
                  
               elseif(app.InputRangeDropDown.Value=="Angle of Crank    , θ_2")
                    if(app.LinkDropDown_2.Value=="Ang. Acc of Connecting Rod")
                         plot(T,alpha_conn,"Parent",app.UIAxes3);
                         app.UIAxes3.XLabel.String="Time (sec)";
                         app.UIAxes3.YLabel.String="Ang. Acc. Of Connecting Rod";
                         app.UIAxes3.Title.String="Acceleration Aanlysis"; 
                    elseif(app.LinkDropDown_2.Value=="Acceleration of Slider")
                         plot(T,acc_s,"Parent",app.UIAxes3);
                         app.UIAxes3.XLabel.String="Time (sec)";
                         app.UIAxes3.YLabel.String="Acceleration of Slider";
                         app.UIAxes3.Title.String="Acceleration Aanlysis"; 
                    end
                 
              end
              
              app.r1=r1;
              app.r2=r2;
              app.r3=r3;
              app.theta2=theta2;
              app.theta3=theta3;
              app.w_crank=w_crank;
              app.w_conn=w_conn;
              app.alpha_conn=alpha_conn;
              app.alpha_crank=alpha_crank;
              app.v_s=v_s;


        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            
            theta2=deg2rad(changingValue);
            r4=0;r2=0.8;r3=2.5;
 
           app.TextArea_ani.Value="";
          

app.UIAxes4.Title.String = " Inline Slider Crank Mechanism "
app.UIAxes4.XLabel.String = "X (m)";
app.UIAxes4.YLabel.String = "Y (m)";


    theta3 = asin( (r4-r2*sin(theta2)) / r3);
    r1 = r2*cos(theta2) + r3*cos(theta3);
    
     plot([0 r2*cos(theta2)], [0 r2*sin(theta2)],'ro-','LineWidth',2,"DisplayName","Crank Link ,r2","Parent",app.UIAxes4);
     hold(app.UIAxes4,'on');
     plot([r2*cos(theta2) r2*cos(theta2)+r3*cos(theta3)], [r2*sin(theta2) r4], 'go-','LineWidth',2,"DisplayName","Connecting Rod , r3","Parent",app.UIAxes4);
     hold(app.UIAxes4,'on');
     r =rectangle("Position",[(r2*cos(theta2)+r3*cos(theta3))-0.5 -0.2 1 0.4],"Curvature",0.2,"Parent",app.UIAxes4);
     r.FaceColor='black';
     hold(app.UIAxes4,'off');
     A =(r2+r3)-(r2*cos(theta2)+r3*cos(theta3));
     app.TextArea_ani.Value="Displacement of x : "+num2str(A)+"                         Angle theta2 = " + num2str(changingValue,4);
     legend(app.UIAxes4)

      
  if(app.Switch_2.Value=="Pause")
         waitfor(app.Switch_2,'Value',"Animate");
  elseif(app.Switch_2.Value=="Animate")
         pause(0.2);

     end


        end

        % Button pushed function: DisplayButton
        function DisplayButtonPushed(app, event)

theta2 = 0:0.1:(8*pi);
len = length(theta2);
r4=0;r2=0.8;r3=2.5;
 
           app.TextArea_ani.Value="";
           app.Slider.Value=0;

app.UIAxes4.Title.String = " Inline Slider Crank Mechanism "
app.UIAxes4.XLabel.String = "X (m)";
app.UIAxes4.YLabel.String = "Y (m)";


for i=1:len


    theta3(i) = asin( (r4-r2*sin(theta2(i))) / r3);
    r1(i) = r2*cos(theta2(i)) + r3*cos(theta3(i));
    
     plot([0 r2*cos(theta2(i))], [0 r2*sin(theta2(i))],'ro-','LineWidth',2,"DisplayName","Crank Link ,r2","Parent",app.UIAxes4);
     hold(app.UIAxes4,'on');
     plot([r2*cos(theta2(i)) r2*cos(theta2(i))+r3*cos(theta3(i))], [r2*sin(theta2(i)) r4], 'go-','LineWidth',2,"DisplayName","Connecting Rod , r3","Parent",app.UIAxes4);
     hold(app.UIAxes4,'on');
     r =rectangle("Position",[(r2*cos(theta2(i))+r3*cos(theta3(i)))-0.5 -0.2 1 0.4],"Curvature",0.2,"Parent",app.UIAxes4);
     r.FaceColor='black';
     hold(app.UIAxes4,'off');
     A =(r2+r3)-(r2*cos(theta2(i))+r3*cos(theta3(i)));
     app.TextArea_ani.Value="Displacement of x : "+num2str(A)+"                         Angle theta2 = " + num2str(theta2(i),4),;
     legend(app.UIAxes4)

 if(app.Switch_2.Value=="Pause")
         waitfor(app.Switch_2,'Value',"Animate");
  elseif(app.Switch_2.Value=="Animate")
         pause(0.1);
  else
      break
   
     end
   
end
          
        end

        % Button pushed function: EvaluateButton_Force
        function EvaluateButton_ForcePushed(app, event)
           [r1,r2,r3,theta2,theta3]=Position(app);
           if(app.InputRangeDropDown.Value=="Position of slider , r ⃗_1")
               app.VelocityEditFieldLabel.Text="Velocity of Slider";
              
           elseif(app.InputRangeDropDown.Value=="Angle of Crank    , θ_2")
                app.VelocityEditFieldLabel.Text="Ang. Velocity Of Crank";
             
           end
            app.InputVelocityEditField.Value = app.VelocityEditFieldForce.Value;
           [w_crank,w_conn,v_s,v_crank,v_conn,T]=Velocity(app);
           [alpha_crank,alpha_conn,acc_s]=Acceleration(app);
           
         [P,theta,Torque,For]=Force(app);
           plot(T,Torque,"Parent",app.UIAxes5)
           app.UIAxes5.XLabel.String="Time (sec)";
           app.UIAxes5.YLabel.String="Torque";
           app.UIAxes5.Title.String="Static Force Analysis";


        end

        % Button pushed function: SaveOutputButton
        function SaveOutputButtonPushed(app, event)
             
              [r1,r2,r3,theta2,theta3]=Position(app);
           if(app.InputRangeDropDown.Value=="Position of slider , r ⃗_1")
               app.VelocityEditFieldLabel.Text="Velocity of Slider";
              
           elseif(app.InputRangeDropDown.Value=="Angle of Crank    , θ_2")
                app.VelocityEditFieldLabel.Text="Ang. Velocity Of Crank";
             
           end
            app.InputVelocityEditField.Value = app.VelocityEditFieldForce.Value;
           [w_crank,w_conn,v_s,v_crank,v_conn,T]=Velocity(app);
           [alpha_crank,alpha_conn,acc_s]=Acceleration(app);
          
           [P,theta,Torque,For]=Force(app);


  prompt={'Save As : '}; 
  dlg_title=' Input';
  num_lines = [1 50] ; 
  def={''};
  out=inputdlg(prompt,dlg_title,num_lines,def);
  filename=[out{1} '.txt'];
  file=fopen(filename,'wt');
           
  
  if(app.InputRangeDropDown.Value=="Position of slider , r ⃗_1")
      
       
      t1=categorical(T');
      t2=categorical(r1');
      t3=categorical(theta2'); 
      t4=categorical(theta3');
      t5=categorical(w_crank');
      t6=categorical(w_conn');
      t7=categorical(alpha_crank');
      t8=categorical(alpha_conn');
      
column={'T(sec)','Displacement of Slider(m)','Theta2(degrees)','Theta3(degrees)','Ang.vel of Crank(rad /s)','Ang.vel of Conn. Rod(rad /s)','Ang .Acc. Of Crank(rad/s^2)','Ang .Acc. Of Conn. Rod(rad/s^2)'};

Title='Inline - Slider Crank Mechanism'; fprintf(file,'%s\n',Title);
% Prinitng all data
str1='Crank Length = %d m (Crank)\n'; fprintf(file,str1,r2);
str2='Length of Connecting Rod = %d m (Slider)\n'; fprintf(file,str2,r3);
str3='Velocity of Slider = %d m/s (Fixed)\n'; fprintf(file,str3,v_s);
      
  Table=table(t1,t2,t3,t4,t5,t6,t7,t8,'VariableNames',column);    
      
  elseif(app.InputRangeDropDown.Value=="Angle of Crank    , θ_2")
     
    
      t1=categorical(T');
      t2=categorical(theta2'); 
      t3=categorical(theta3');
      t4=categorical(v_s');
      t5=categorical(w_conn');
  
      t6=categorical(round(acc_s)');
      t7=categorical(alpha_conn');
      
column={'T','Theta2(degrees)','Theta3(degrees)','Velocity of Slider(m/s)','Ang.vel of Conn. Rod(rad /s)','Acceleration Of Slider(m/s^2)','Ang .Acc. Of Conn. Rod(rad/s^2)'};

Title='_____ ----- Inline - Slider Crank Mechanism ----- _____'; fprintf(file,'%s\n',Title);

str1='Crank Length = %d m (Crank)\n'; fprintf(file,str1,r2);
str2='Length of Connecting Rod = %d m (Slider)\n'; fprintf(file,str2,r3);
str3='Angular Velocity of Crank = %d rad/s (Fixed)\n'; fprintf(file,str3,w_crank);
   Table=table(t1,t2,t3,t4,t5,t6,t7,'VariableNames',column);   
  end
  

writetable(Table,'table.txt','Delimiter',"\t");
fclose(file);

tableFile=fileread('table.txt');
[Original msg]=fopen(filename,'at');
fprintf(Original,'\n_____ Output Table _____');
fprintf(Original,'\n\n%s',tableFile);

fprintf(file,'____ Stattic Force Analysis ____ \n');

if(For==1)
   str6='The Force applied on the Slider  = %d\n'; fprintf(file,str6,P); 
   str7='Angle of Force Action  = %d\n'; fprintf(file,str7,theta); 
   str8='The Value of Torque = \n';fprintf(file,str8); 
   str9='%d \n';fprintf(file,str9,Torque');
   
elseif(For==2)
     str6='The Force applied on the mid point Connecting Rod  = %d\n'; fprintf(file,str6,P); 
   str7='Angle of Force Action  = %d\n'; fprintf(file,str7,theta); 
   str8='The Value of Torque = \n';fprintf(file,str8);
   str9='%d \n';fprintf(file,str9,Torque');
else
    str6='No Force Analysis or No External Force is Acting'; fprintf(file,str6); 
end

fclose(file);

           
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.AnalyseButton.Enable="off";
            app.InputRangeEditField.Value = "";
            app.ConnectingrodLengthmEditField.Value="";
            app.CrankLengthmEditField.Value="";
           
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            cla(app.UIAxes5);
        end

        % Button pushed function: ResetButton_6
        function ResetButton_6Pushed(app, event)
            
            app.VelocityEditFieldForce.Value=0;
            app.PNEditField.Value=0;
            app.AngleofActionEditField.Value=0;
        end

        % Button pushed function: ClearButton_4
        function ClearButton_4Pushed(app, event)
            cla(app.UIAxes);
        end

        % Button pushed function: ClearButton_3
        function ClearButton_3Pushed(app, event)
            
            cla(app.UIAxes3);
            app.InputVelocity_accEditField.Value=0;
            
        end

        % Button pushed function: ClearButton_2
        function ClearButton_2Pushed(app, event)
            cla(app.UIAxes2);
            app.InputVelocityEditField.Value=0;
        end

        % Value changed function: InputRangeDropDown
        function InputRangeDropDownValueChanged(app, event)
            value = app.InputRangeDropDown.Value;
           if(app.InputRangeDropDown.Value=="Position of slider , r ⃗_1")
               app.InputRangeEditFieldLabel.Text="Displacement of Slider (m)";
              
              elseif(app.InputRangeDropDown.Value=="Angle of Crank    , θ_2")
                  app.InputRangeEditFieldLabel.Text="Angle of crank (deg)";
             
            end


        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            value = app.Switch_3.Value;
            
            if(value=="On")
                app.MainFrame.Enable="on";
            elseif(value=="Off")
                app.MainFrame.Enable="off";
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ForceFrame = uipanel(app.UIFigure);
            app.Button_Force = uibutton(app.ForceFrame, 'push');
            app.Button_Force.ButtonPushedFcn = createCallbackFcn(app, @Button_ForcePushed, true);
            app.Button_Force.Text = '←';
            app.PonLinkDropDownLabel = uilabel(app.ForceFrame);
            app.PonLinkDropDownLabel.Text = 'P on Link';
            app.PonLinkDropDown = uidropdown(app.ForceFrame);
            app.PonLinkDropDown.Items = {'Connecting Rod', 'Slider'};
            app.PonLinkDropDown.ItemsData = {'Connecting Rod', 'Slider'};
            app.PonLinkDropDown.Value = 'Connecting Rod';
            app.PNEditFieldLabel = uilabel(app.ForceFrame);
            app.PNEditFieldLabel.Text = 'P (N)';
            app.PNEditField = uieditfield(app.ForceFrame, 'numeric');
            app.EvaluateButton_Force = uibutton(app.ForceFrame, 'push');
            app.EvaluateButton_Force.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButton_ForcePushed, true);
            app.EvaluateButton_Force.Text = 'Evaluate ';
            app.ResetButton_6 = uibutton(app.ForceFrame, 'push');
            app.ResetButton_6.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_6Pushed, true);
            app.ResetButton_6.Text = 'Reset';
            app.clearButton = uibutton(app.ForceFrame, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            app.AngleofActionEditFieldLabel = uilabel(app.ForceFrame);
            app.AngleofActionEditFieldLabel.Text = 'Angle of Action';
            app.AngleofActionEditField = uieditfield(app.ForceFrame, 'numeric');
            app.VelocityEditFieldLabel = uilabel(app.ForceFrame);
            app.VelocityEditFieldLabel.Text = 'Velocity';
            app.VelocityEditFieldForce = uieditfield(app.ForceFrame, 'numeric');
            app.UIAxes5 = uiaxes(app.ForceFrame);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.PositionFrame = uipanel(app.UIFigure);
            app.Button_Position = uibutton(app.PositionFrame, 'push');
            app.Button_Position.ButtonPushedFcn = createCallbackFcn(app, @Button_PositionPushed, true);
            app.Button_Position.Text = '←';
            app.LinkDropDownLabel = uilabel(app.PositionFrame);
            app.LinkDropDownLabel.Text = 'Link';
            app.LinkDropDown = uidropdown(app.PositionFrame);
            app.LinkDropDown.Items = {'Crank', 'Slider'};
            app.LinkDropDown.ItemsData = {'Crank', 'Slider'};
            app.LinkDropDown.Value = 'Crank';
            app.EvaluateButton_pos = uibutton(app.PositionFrame, 'push');
            app.EvaluateButton_pos.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButton_posPushed, true);
            app.EvaluateButton_pos.Text = 'Evaluate';
            app.ClearButton_4 = uibutton(app.PositionFrame, 'push');
            app.ClearButton_4.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_4Pushed, true);
            app.ClearButton_4.Text = 'Clear';
            app.UIAxes = uiaxes(app.PositionFrame);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AccelerationFrame = uipanel(app.UIFigure);
            app.Button_Acc = uibutton(app.AccelerationFrame, 'push');
            app.Button_Acc.ButtonPushedFcn = createCallbackFcn(app, @Button_AccPushed, true);
            app.Button_Acc.Text = '←';
            app.EvaluateButton = uibutton(app.AccelerationFrame, 'push');
            app.EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButton_accPushed, true);
            app.EvaluateButton.Text = 'Evaluate';
            app.LinkDropDown_2Label = uilabel(app.AccelerationFrame);
            app.LinkDropDown_2Label.Text = 'Link';
            app.LinkDropDown_2 = uidropdown(app.AccelerationFrame);
            app.LinkDropDown_2.Items = {};
            app.LinkDropDown_2.Value = {};
            app.InputvelocityEditFieldLabel = uilabel(app.AccelerationFrame);
            app.InputvelocityEditFieldLabel.Text = 'Input velocity ';
            app.InputVelocity_accEditField = uieditfield(app.AccelerationFrame, 'numeric');
            app.ClearButton_3 = uibutton(app.AccelerationFrame, 'push');
            app.ClearButton_3.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_3Pushed, true);
            app.ClearButton_3.Text = 'Clear';
            app.UIAxes3 = uiaxes(app.AccelerationFrame);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.VelocityFrame = uipanel(app.UIFigure);
            app.VelocityFrame.SizeChangedFcn = createCallbackFcn(app, @EvaluateButton_posPushed, true);
            app.Button_vel = uibutton(app.VelocityFrame, 'push');
            app.Button_vel.ButtonPushedFcn = createCallbackFcn(app, @Button_velPushed, true);
            app.Button_vel.Text = '←';
            app.EvaluateButton_vel = uibutton(app.VelocityFrame, 'push');
            app.EvaluateButton_vel.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButton_velPushed, true);
            app.EvaluateButton_vel.Text = 'Evaluate ';
            app.TypeDropDownLabel = uilabel(app.VelocityFrame);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.VelocityFrame);
            app.TypeDropDown.Items = {};
            app.TypeDropDown.Value = {};
            app.InputVelocityEditFieldLabel = uilabel(app.VelocityFrame);
            app.InputVelocityEditFieldLabel.Text = 'Input Velocity';
            app.InputVelocityEditField = uieditfield(app.VelocityFrame, 'numeric');
            app.ClearButton_2 = uibutton(app.VelocityFrame, 'push');
            app.ClearButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_2Pushed, true);
            app.ClearButton_2.Text = 'Clear';
            app.UIAxes2 = uiaxes(app.VelocityFrame);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.AnimationFrame = uipanel(app.UIFigure);
            app.Button_Animate = uibutton(app.AnimationFrame, 'push');
            app.Button_Animate.ButtonPushedFcn = createCallbackFcn(app, @Button_AnimatePushed, true);
            app.Button_Animate.Text = '←';
            app.SliderLabel = uilabel(app.AnimationFrame);
            app.SliderLabel.Text = 'θ_2';
            app.Slider = uislider(app.AnimationFrame);
            app.Slider.Limits = [0 360];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.Switch_2 = uiswitch(app.AnimationFrame, 'slider');
            app.Switch_2.Items = {'Pause', 'Animate'};
            app.Switch_2.Value = 'Pause';
            app.TextArea_ani = uitextarea(app.AnimationFrame);
            app.DisplayButton = uibutton(app.AnimationFrame, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.DisplayButton.Text = 'Display';
            app.UIAxes4 = uiaxes(app.AnimationFrame);
            app.UIAxes4.XLim = [-2 6];
            app.UIAxes4.YLim = [-2 2];
            app.MainFrame = uipanel(app.UIFigure);
            app.CrankLengthmEditFieldLabel = uilabel(app.MainFrame);
            app.CrankLengthmEditFieldLabel.Text = 'Crank Length (m)';
            app.CrankLengthmEditField = uieditfield(app.MainFrame, 'text');
            app.ConnectingrodLengthmEditFieldLabel = uilabel(app.MainFrame);
            app.ConnectingrodLengthmEditFieldLabel.Text = 'Connecting rod Length (m)';
            app.ConnectingrodLengthmEditField = uieditfield(app.MainFrame, 'text');
            app.ValidateButton = uibutton(app.MainFrame, 'push');
            app.ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateButtonPushed, true);
            app.ValidateButton.Text = 'Validate';
            app.InputRangeEditFieldLabel = uilabel(app.MainFrame);
            app.InputRangeEditFieldLabel.Text = 'Input Range';
            app.InputRangeEditField = uieditfield(app.MainFrame, 'text');
            app.EditField = uieditfield(app.MainFrame, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'In - Line Slider Crank Mechanism Analyser';
            app.ValidationTextAreaLabel = uilabel(app.MainFrame);
            app.ValidationTextAreaLabel.Text = 'Validation';
            app.ValidationTextArea = uitextarea(app.MainFrame);
            app.ValidationTextArea.Editable = 'off';
            app.AnalyseButton = uibutton(app.MainFrame, 'push');
            app.AnalyseButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseButtonPushed, true);
            app.AnalyseButton.Enable = 'off';
            app.AnalyseButton.Text = 'Analyse';
            app.InputRangeDropDownLabel = uilabel(app.MainFrame);
            app.InputRangeDropDownLabel.Text = 'Input Range';
            app.InputRangeDropDown = uidropdown(app.MainFrame);
            app.InputRangeDropDown.Items = {'Position of slider , r ⃗_1', 'Angle of Crank    , θ_2'};
            app.InputRangeDropDown.ValueChangedFcn = createCallbackFcn(app, @InputRangeDropDownValueChanged, true);
            app.InputRangeDropDown.Value = 'Position of slider , r ⃗_1';
            app.AnimateButton = uibutton(app.MainFrame, 'push');
            app.AnimateButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateButtonPushed, true);
            app.AnimateButton.Text = 'Animate';
            app.KinematicsDropDownLabel = uilabel(app.MainFrame);
            app.KinematicsDropDownLabel.Text = 'Kinematics';
            app.KinematicsDropDown = uidropdown(app.MainFrame);
            app.KinematicsDropDown.Items = {'Position', 'Velocity', 'Acceleration', 'Static Force'};
            app.KinematicsDropDown.ItemsData = {'Position', 'Velocity', 'Acceleration', 'Static Force', ''};
            app.KinematicsDropDown.Value = 'Position';
            app.ResetButton = uibutton(app.MainFrame, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SaveOutputButton = uibutton(app.MainFrame, 'push');
            app.SaveOutputButton.ButtonPushedFcn = createCallbackFcn(app, @SaveOutputButtonPushed, true);
            app.SaveOutputButton.Text = 'Save Output ';
            app.Switch_3 = uiswitch(app.UIFigure, 'slider');
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mech_Inline_Slider_Crank_Mechanism

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end